classdef DAFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        g_led                      matlab.ui.control.Lamp
        y_led                      matlab.ui.control.Lamp
        RyersonHyperloopDAFTLabel  matlab.ui.control.Label
        tare_btn                   matlab.ui.control.Button
        text_output                matlab.ui.control.EditField
        read_btn                   matlab.ui.control.Button
        start_btn                  matlab.ui.control.Button
        end_btn                    matlab.ui.control.Button
        SpeedmsGaugeLabel          matlab.ui.control.Label
        speed_gauge                matlab.ui.control.Gauge
        r_led                      matlab.ui.control.Lamp
        connect_btn                matlab.ui.control.Button
        Cell1NLabel                matlab.ui.control.Label
        cell1_gauge                matlab.ui.control.Gauge
        Cell2NLabel                matlab.ui.control.Label
        cell2_gauge                matlab.ui.control.Gauge
    end

    
    properties (Access = private)
        due=0;                                            % Variable to hold Comm link
        running=0;                                        % Program Status
        connect=0;                                        % Connection Status
        data=zeros(10000,4);                                       % App data variable
        daft=daftStruct;                                  % Data structure
        ctr=1;
    end
    

    methods (Access = private)

        % Button pushed function: tare_btn
        function tare_btnButtonPushed(app, event)
            app.y_led.Enable='on';
            app.g_led.Enable='off';
            app.r_led.Enable='off';
            app.text_output.Value='Tare in Progress...';
            if ((~app.running)&& (app.connect))
                [ack]=tareGauge(app.due);
                if ack==1
                    app.text_output.Value='Tare Successfull';
                    app.y_led.Enable='off';
                    app.g_led.Enable='on';
                else
                    app.text_output.Value='Tare failed';
                    app.y_led.Enable='off';
                    app.r_led.Enable='on';
                end
            end
        end

        % Button pushed function: read_btn
        function read_btnButtonPushed(app, event)
            app.y_led.Enable='on';
            app.g_led.Enable='off';
            app.r_led.Enable='off';
            if ((~app.running)&& (app.connect))
                [f1,f2,speed,ack]=readCell(app.due);
                if ack==1
                    msg=strcat('Lift: ',string(f1),'N     Drag: ',string(f2),'N');
                    app.text_output.Value=msg;
                    
                    % Display
                    app.speed_gauge.Value=speed;
                    app.cell2_gauge.Value=f2;
                    app.cell1_gauge.Value=f1;
                    
                    app.y_led.Enable='off';
                    app.g_led.Enable='on';
                else
                    app.text_output.Value='Read failed';
                    app.y_led.Enable='off';
                    app.r_led.Enable='on';
                end
            end
        end

        % Button pushed function: start_btn
        function start_btnButtonPushed(app, event)
            if ((~(app.running==1)) && (app.connect==1))
                [ack]=startDAFT(app.due);
                if ack==1
                    app.text_output.Value='Aquisition Started';
                    app.running=1;
                    while app.running
                        str=fscanf(app.due,'%s');
                        app.data(app.ctr,:)=(str2double(split(str,';')))';
                        %app.daft.Cell_1.Log=app.data(app.ctr,1);
                        %app.daft.Cell_2.Log=app.data(app.ctr,2);
                        %app.daft.SpeedSensor.Log=app.data(app.ctr,3)
                        
                        % Display
                        app.speed_gauge.Value=app.data(app.ctr,3);
                        app.cell2_gauge.Value=app.data(app.ctr,2);
                        app.cell1_gauge.Value=app.data(app.ctr,1);
                        app.ctr=app.ctr+1;
                        
                        %plot(app.graph_output,app.data(:,3),app.data(:,1));%,app.data(:,2),app.data(:,3));
                        pause(.005)
                    end
                    
                else
                    app.text_output.Value='Aquisition Failed';
                end
                
                
            end
        end

        % Button pushed function: end_btn
        function end_btnButtonPushed(app, event)
            if app.running
                stopDAFT(app.due);
                while app.running
                    str=fscanf(app.due,'%s');
                    if size(str,1)<4
                        app.running=0;
                        app.text_output.Value='Aquisiton Ended';
                        filePath='C:\Users\nathan\Documents\MATLAB\Hyperloop';
                        dat=app.data;
                        saveDat='dat';
                        uisave(saveDat,filePath)
                    else
                        app.data(app.ctr,:)=(str2double(split(str,';')))';
                        %app.daft.Cell_1.Log=app.data(app.ctr,1);
                        %app.daft.Cell_2.Log=app.data(app.ctr,2);
                        %app.daft.SpeedSensor.Log=app.data(app.ctr,3);
                        
                        % Display
                        app.speed_gauge.Value=app.data(app.ctr,3);
                        app.cell2_gauge.Value=app.data(app.ctr,2);
                        app.cell1_gauge.Value=app.data(app.ctr,1);
                        app.ctr=app.ctr+1;
                        
                        %plot(app.graph_output,app.data(:,3),app.data(:,1));%,app.data(:,2),app.data(:,3));
                        pause(.005)
                    end
                end
                
            end
        end

        % Button pushed function: connect_btn
        function connect_btnButtonPushed(app, event)
            app.y_led.Enable='on';
            app.g_led.Enable='off';
            app.r_led.Enable='off';
            
            if ~isempty(instrfindall)
                fclose(instrfindall);
            end
            
            if ~app.connect
                app.text_output.Value='Connecting to Arduino...';
                pause(0.5)
                [app.due,ack]=connectToDAFT('com6');
                if ack==1
                    app.text_output.Value='Connection Successfull';
                    app.connect=1;
                    app.y_led.Enable='off';
                    app.g_led.Enable='on';
                else
                    app.text_output.Value='Connection failed';
                    app.y_led.Enable='off';
                    app.g_led.Enable='on';
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.g_led = uilamp(app.UIFigure);
            app.g_led.Enable = 'off';
            app.y_led = uilamp(app.UIFigure);
            app.y_led.Enable = 'off';
            app.RyersonHyperloopDAFTLabel = uilabel(app.UIFigure);
            app.RyersonHyperloopDAFTLabel.Text = 'Ryerson Hyperloop -DAFT';
            app.tare_btn = uibutton(app.UIFigure, 'push');
            app.tare_btn.ButtonPushedFcn = createCallbackFcn(app, @tare_btnButtonPushed, true);
            app.tare_btn.Text = 'Tare';
            app.text_output = uieditfield(app.UIFigure, 'text');
            app.read_btn = uibutton(app.UIFigure, 'push');
            app.read_btn.ButtonPushedFcn = createCallbackFcn(app, @read_btnButtonPushed, true);
            app.read_btn.Text = 'Read';
            app.start_btn = uibutton(app.UIFigure, 'push');
            app.start_btn.ButtonPushedFcn = createCallbackFcn(app, @start_btnButtonPushed, true);
            app.start_btn.Text = 'Start';
            app.end_btn = uibutton(app.UIFigure, 'push');
            app.end_btn.ButtonPushedFcn = createCallbackFcn(app, @end_btnButtonPushed, true);
            app.end_btn.Text = 'End';
            app.SpeedmsGaugeLabel = uilabel(app.UIFigure);
            app.SpeedmsGaugeLabel.Text = 'Speed (m/s)';
            app.speed_gauge = uigauge(app.UIFigure, 'circular');
            app.speed_gauge.Limits = [0 30];
            app.r_led = uilamp(app.UIFigure);
            app.r_led.Enable = 'off';
            app.connect_btn = uibutton(app.UIFigure, 'push');
            app.connect_btn.ButtonPushedFcn = createCallbackFcn(app, @connect_btnButtonPushed, true);
            app.connect_btn.Text = 'Connect to Arduino';
            app.Cell1NLabel = uilabel(app.UIFigure);
            app.Cell1NLabel.Text = 'Cell 1 (N)';
            app.cell1_gauge = uigauge(app.UIFigure, 'circular');
            app.cell1_gauge.Limits = [0 1000];
            app.Cell2NLabel = uilabel(app.UIFigure);
            app.Cell2NLabel.Text = 'Cell 2 (N)';
            app.cell2_gauge = uigauge(app.UIFigure, 'circular');
            app.cell2_gauge.Limits = [0 1000];
        end
    end

    methods (Access = public)

        % Construct app
        function app = DAFT

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end